classdef SerialPortSensorApp_4FPC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        NMEALogTab               matlab.ui.container.Tab
        LogGrid                  matlab.ui.container.GridLayout
        NMEAStringTextArea       matlab.ui.control.TextArea
        FilterByDropDown         matlab.ui.control.DropDown
        FilterByDropDownLabel    matlab.ui.control.Label
        LiveDataGrid             matlab.ui.container.GridLayout
        S4zField                 matlab.ui.control.EditField
        S4yField                 matlab.ui.control.EditField
        S4xField                 matlab.ui.control.EditField
        S4zLabel                 matlab.ui.control.Label
        S4yLabel                 matlab.ui.control.Label
        S4xLabel                 matlab.ui.control.Label
        FyField                  matlab.ui.control.EditField
        FxField                  matlab.ui.control.EditField
        FzField                  matlab.ui.control.EditField
        FzLabel                  matlab.ui.control.Label
        FyLabel                  matlab.ui.control.Label
        FxLabel                  matlab.ui.control.Label
        Y_LocationField          matlab.ui.control.EditField
        Y_LocationLabel          matlab.ui.control.Label
        X_LocationField          matlab.ui.control.EditField
        X_LocationLabel          matlab.ui.control.Label
        S3zField                 matlab.ui.control.EditField
        S3zLabel                 matlab.ui.control.Label
        S3yField                 matlab.ui.control.EditField
        S3yLabel                 matlab.ui.control.Label
        S3xField                 matlab.ui.control.EditField
        S3xLabel                 matlab.ui.control.Label
        S2zField                 matlab.ui.control.EditField
        S2zLabel                 matlab.ui.control.Label
        S2yField                 matlab.ui.control.EditField
        S2yLabel                 matlab.ui.control.Label
        S2xField                 matlab.ui.control.EditField
        S2xLabel                 matlab.ui.control.Label
        S1zField                 matlab.ui.control.EditField
        S1zLabel                 matlab.ui.control.Label
        S1yField                 matlab.ui.control.EditField
        S1yLabel                 matlab.ui.control.Label
        S1xField                 matlab.ui.control.EditField
        S1xLabel                 matlab.ui.control.Label
        SensorStatusField        matlab.ui.control.EditField
        SensorStatusLamp         matlab.ui.control.Lamp
        SensorstatusLabel        matlab.ui.control.Label
        ConfigPanel              matlab.ui.container.Panel
        ConfigGrid               matlab.ui.container.GridLayout
        FileLoggingPanel         matlab.ui.container.Panel
        FileLoggingGrid          matlab.ui.container.GridLayout
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        FileSelectButton         matlab.ui.control.Button
        FilenameEditField        matlab.ui.control.EditField
        FilenameLabel            matlab.ui.control.Label
        ConfigurationPanel       matlab.ui.container.Panel
        ConfigurationGrid        matlab.ui.container.GridLayout
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        BaudrateDropDown         matlab.ui.control.DropDown
        BaudRateLabel            matlab.ui.control.Label
        RefreshButton            matlab.ui.control.Button
        SerialportDropDown       matlab.ui.control.DropDown
        SerialportDropDownLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        % App Flags
        ChecksumAlert = false           % Display warning for invalid checksum

        % Object Handles
        Plot                            % Handle to "Plot View" line object

        % File Selection and Logging
        Filename = "Sensor_data.txt"       % Default file name for logging
        FilePath = pwd                  % Default file path for logging
        RawFilename = "Sensor_data_raw.txt"% Default name for raw logging file
        SerialPort                      % Handle to serialport object
        Logging = false                 % Boolean for whether we are logging to file or not
        FileInitialized = false
        CycleEnd

        % Live Updating Variables (latest point)
        FixType double
        S1x double
        S1y double
        S1z double
        S2x double
        S2y double
        S2z double
        S3x double
        S3y double
        S3z double
        S4x double
        S4y double
        S4z double

        % 1是底面，从近接口端到远接口端，分别是2,3,4

        X_Location double
        Y_Location double

        Fx double
        Fy double
        Fz double

        S1x_01 double % used for update static state signal
        S1y_01 double
        S1z_01 double
        S2x_01 double 
        S2y_01 double
        S2z_01 double
        S3x_01 double 
        S3y_01 double
        S3z_01 double
        S4x_01 double 
        S4y_01 double
        S4z_01 double
        Count double % used for adjusting whether it is initial state


        % Data saved over time
        NMEARaw                         % For "NMEA Log" tab
        DataTable                       % For last 10 minutes of "live updating" data
        LastMinutes = 10                % Number of latest minutes to preserve
        LastLines = 500                 % Number of latest lines to preserve
        TableVariables = {              % Header for data table
            'ComputerTime', ...
            'S1x', ...
            'S1y', ...
            'S1z', ...
            'S2x', ...
            'S2y', ...
            'S2z', ...
            'S3x', ...
            'S3y', ...
            'S3z'};
    end

%=====================================================================================
    methods (Access = private)
%-------------------------------------------------------------------------------------
        function serialportCallback(app,obj,evt)
            %   serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states

            % Handle Data state
            inputline = readline(obj);
            % Extract callback timestamp from evt data
            eventtime = datetime(evt.AbsTime,Format='yyyy-MM-dd''T''HH:mm:ss.SSS');

            % Process Data state
            latestdata = processData(app,inputline,eventtime);

            if app.Logging
                % Log Data state
                logData(app,inputline,latestdata);
            end

            % Update Live View state
            updateLiveView(app)
        end
%-------------------------------------------------------------------------------------
        function newrow = processData(app,inputline,dt)
            %   processData Read and processes the newest line,
            %   save processed data to app properties for later use.
            %   This includes all of the "Live Data", "Track Data", meaning
            %   data saved over time, and the newest LastLines number of NMEA
            %   raw data sentences

            newrow = {dt};

            % Run checksum for inputline; if checksum is invalid, do not process the line (return early)
            if ~validateChecksum(app,inputline)
                if app.ChecksumAlert
                    warning("Checksum is wrong for " + inputline);
                end
            end

                results = Split_signal(app,inputline);
                updateAppProperties(app,results);
                
                if ~isempty(app.S1y)
                    newrow = {newrow{1}, app.FixType, app.S1y, app.S1z, app.S1x, app.S2x, app.S3x, app.S2y, app.S2z, app.S3z, app.S3y};
                    app.DataTable = [app.DataTable; newrow]; 
                end
         
            % Save NMEA raw data over time, with LastLines lines max
            if numel(app.NMEARaw) < app.LastLines
                app.NMEARaw = [inputline app.NMEARaw];
            else
                app.NMEARaw = circshift(app.NMEARaw,1);
                app.NMEARaw(1) = inputline;
            end
        end
%-------------------------------------------------------------------------------------
        function updateAppProperties(app,values)
            %   updateAppProperties Update the app properties for the input
            %   sentence based on the given values. For example, if sentence
            %   was "GPGGA", then the GPGGA-related app properties will be
            %   updated. values contains a struct of the necessary data.
                    app.S1x = values.S1x;
                    app.S1y = values.S1y;
                    app.S1z = values.S1z;
                    app.S2x = values.S2x;
                    app.S2y = values.S2y;
                    app.S2z = values.S2z;
                    app.S3x = values.S3x;
                    app.S3y = values.S3y;
                    app.S3z = values.S3z;
                    app.S4x = values.S4x;
                    app.S4y = values.S4y;
                    app.S4z = values.S4z;

                    app.CycleEnd = 1;
        end
%-------------------------------------------------------------------------------------
        function updateLiveView(app)
            %   updateLiveView will update the Live Data view and various
            %   tabs (depending on which tab is open) to match the "current data"

            if app.CycleEnd % Can update everything if at the end of a data cycle
                %   if app.FixType == 0 % If 0, there is no fix. Clear all Live Data fields
                    %clearLiveDataFields(app);
                %   else % If not 0, then fill in details
                    updateLiveDataFields(app);
                %   end
                % Update graphics based on open tab group
                switch app.TabGroup.SelectedTab.Title
                    case "Tab"
                        updatePlotView(app);
                    case "NMEA Log"
                        updateNMEALog(app);
                end
                app.CycleEnd = 0; % Reset the cycle
            else % If not at the end of a data cycle, only update the NMEA Log
                if app.TabGroup.SelectedTab.Title == "NMEA Log"
                    updateNMEALog(app);
                end
            end
        end
%-------------------------------------------------------------------------------------
        function setAppState(app,state)
            %   setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialportDropDown.Enable = "on";
                    app.BaudrateDropDown.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.RefreshButton.Enable = "on";

                    app.DisconnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.FilterByDropDown.Enable = "off";
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";      
                    app.FilterByDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "WaitForData"
                    % Not logging
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.FilterByDropDown.Enable = "on";
                    app.FilenameEditField.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "Logging"
                    app.DisconnectButton.Enable = "on";
                    app.StopButton.Enable = "on";
                    app.FilterByDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.FilenameEditField.Enable = "off";
                    app.RefreshButton.Enable = "off";
            end
        end
%-------------------------------------------------------------------------------------
        function results = validateChecksum(~,inputline)
            %   validateChecksum confirms that the latest line has a valid
            %   checksum calculation

            if ~startsWith(inputline,"$")
                results = 0;
                return
            end
            splitline = split(inputline,["$" "*"]);
            checksumline = uint8(splitline{2});
            expected = hex2dec(splitline(3));
            checksum = uint8(0);
            % Iterate through each character in checksumline, executing a
            % bit-wise XOR across all characters to validate the checksum
            for character = checksumline
                checksum = bitxor(checksum,character);
            end
            results = checksum == expected;
        end
%-------------------------------------------------------------------------------------
        function updateNMEALog(app)
            % updateNMEALog is a helper function for the NMEA Log tab
                    app.NMEAStringTextArea.Value = app.NMEARaw;
        end
%-------------------------------------------------------------------------------------
        function datastruct = Split_signal(~,inputline)
            % decomposite the signal

            datastruct = struct("S1x",NaN,"S1y",NaN,"S1z",NaN,"S2x",NaN,"S2y",NaN,"S2z",NaN,"S3x",NaN,"S3y",NaN,"S3z",NaN,"S4x",NaN,"S4y",NaN,"S4z",NaN);
            splitdata = split(inputline,",");
                datastruct.S1x = str2double(splitdata{1});
                datastruct.S1y = str2double(splitdata{2});
                datastruct.S1z = str2double(splitdata{3});
                datastruct.S2x = str2double(splitdata{4});
                datastruct.S2y = str2double(splitdata{5});
                datastruct.S2z = str2double(splitdata{6});
                datastruct.S3x = str2double(splitdata{7});
                datastruct.S3y = str2double(splitdata{8});
                datastruct.S3z = str2double(splitdata{9});
                datastruct.S4x = str2double(splitdata{10});
                datastruct.S4y = str2double(splitdata{11});
                datastruct.S4z = str2double(splitdata{12});

        end
%-------------------------------------------------------------------------------------
        function logData(app,inputline,row)
            %   logData Represents the Log Data state for app. If FileInitialized is
            %   false, initialize files and log to file. Otherwise, just log to
            %   file

            if ~app.FileInitialized
                initializeProcessedDataFile(app);
            end
            logToFile(app,inputline,row);
        end
%-------------------------------------------------------------------------------------
        function logToFile(app,inputline,row)
            %   logToFile Write to raw data file and processed data file. Because
            %   inputline is a single line, use writelines for the raw file.
            %   Because row is a cell array, use writecell for processed file.

            % Log raw data
            dt = row{1};
            writelines(string(dt)+" "+inputline,app.RawFilename,WriteMode="append")

            % Log parsed data
            if app.CycleEnd
                writecell(row,app.FilenameEditField.Value,WriteMode="append")
            end
        end
%-------------------------------------------------------------------------------------
        function initializeProcessedDataFile(app)
            %   initializeProcessedDataFile Write header to file that logs all
            %   data (not raw file). If there is an error, disable logging
            %   and display error. Allow file logging with FileInitialized.

            try
                writecell(app.TableVariables,app.FilenameEditField.Value,WriteMode="append")
                app.FileInitialized = true;
            catch ME
                uialert(app.UIFigure,ME.message,"File Error",Interpreter="html")
                app.Logging = false;
            end
        end
%-------------------------------------------------------------------------------------
        function [processedfile, rawfile] = createFileName(app,filename)
            %   createFileName Check if the file name and corresponding raw file
            %   name meets expected format, meaning its extension is .TXT and
            %   it is not an already existing file. If the file name exists,
            %   return new, auto-indexed name.
            %   For example, if '<datafolder>\GPS_data.txt' exists, return the
            %   next indexed file name in the sequence that does not exist,
            %   such as '<datafolder>\GPS_data_2.txt'

            [filepath, name, ext] = fileparts(filename);
            if ~strcmpi(ext,".txt")
                uialert(app.UIFigure,"File must be in .TXT format","File Log Error")
                ext = ".txt";
            end
            ind = 0;
            processedfile = fullfile(filepath,strcat(name,ext));
            rawfile = fullfile(filepath,strcat(name,"_raw",ext));
            while isfile(processedfile) || isfile(rawfile)
                ind = ind + 1;
                processedfile = fullfile(filepath,strcat(name,"_",num2str(ind),ext));
                rawfile = fullfile(filepath,strcat(name,"_",num2str(ind),"_raw",ext));
            end
        end

%-------------------------------------------------------------------------------------
        function updateLiveDataFields(app)
            %   updateLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to their respective app property
            %   values. Enable the lamp indicating GPS status (valid fix)

            app.SensorStatusLamp.Color = [0 1 0];
            app.SensorStatusField.Value = 'Connected';
            app.S1xField.Value = num2str(app.S1x*(-1) - app.S1x_01);
            app.S1yField.Value = num2str(app.S1y - app.S1y_01);
            app.S1zField.Value = num2str(app.S1z*(-1) - app.S1z_01);
            app.S2xField.Value = num2str(app.S2x*(-1) - app.S2x_01);
            app.S2yField.Value = num2str(app.S2y*(-1) - app.S2y_01);
            app.S2zField.Value = num2str(app.S2z - app.S2z_01);
            app.S3xField.Value = num2str(app.S3x*(-1) - app.S3x_01);
            app.S3yField.Value = num2str(app.S3y*(-1) - app.S3y_01);
            app.S3zField.Value = num2str(app.S3z - app.S3z_01);
            app.S4xField.Value = num2str(app.S4x*(-1) - app.S4x_01);
            app.S4yField.Value = num2str(app.S4y*(-1) - app.S4y_01);
            app.S4zField.Value = num2str(app.S4z - app.S4z_01);

            get_initial_data(app);
            app.Count = app.Count + 1;

            Localization(app);
            app.X_LocationField.Value = num2str(app.X_Location);
            app.Y_LocationField.Value = num2str(app.Y_Location);
            app.FxField.Value = num2str(app.Fx);
            app.FyField.Value = num2str(app.Fy);
            app.FzField.Value = num2str(app.Fz);

send_signal(app);
            
        end


%-------------------------------------------------------------------------------------
function send_signal(app)
% 创建串口对象
portName = 'COM3'; % 根据实际情况更改端口名称
baudRate = 9600;  % 根据实际情况设置波特率

% 创建串口对象
s = serialport(portName, baudRate);

% 配置串口参数（可选）
% configureTerminator(s, 'LF'); % 设置终止符为换行符
% setDTR(s, true);              % 设置数据终端就绪信号
% setRTS(s, true);              % 设置请求发送信号

% 准备要发送的数据
data = app.Fx;

% 将字符串转换为字节数组
dataBytes = num2str(data);

% 发送数据
write(s, dataBytes, "string");

% 清理：关闭串口连接
clear s;

end


%-------------------------------------------------------------------------------------
function get_initial_data(app)
   if app.Count < 1
       app.S1x_01 = app.S1x*(-1);
       app.S1y_01 = app.S1y;
       app.S1z_01 = app.S1z*(-1);
       app.S2x_01 = app.S2x*(-1);
       app.S2y_01 = app.S2y*(-1);
       app.S2z_01 = app.S2z;
       app.S3x_01 = app.S3x*(-1);
       app.S3y_01 = app.S3y*(-1);
       app.S3z_01 = app.S3z;
       app.S4x_01 = app.S4x*(-1);
       app.S4y_01 = app.S4y*(-1);
       app.S4z_01 = app.S4z;


   end
end
%-------------------------------------------------------------------------------------


%-------------------------------------------------------------------------------------
function Localization(app)
          
% initial values     

            dx1_0 = app.S1x*(1) - app.S1x_01;
            dy1_0 = app.S1y - app.S1y_01;
            dz1_0 = app.S1z*(1) - app.S1z_01;
            dx2_0 = app.S2x*(1) - app.S2x_01;
            dy2_0 = app.S2y*(1) - app.S2y_01;
            dz2_0 = app.S2z - app.S2z_01;
            dx3_0 = app.S3x*(1) - app.S3x_01;
            dy3_0 = app.S3y*(1) - app.S3y_01;
            dz3_0 = app.S3z - app.S3z_01;
            dx4_0 = app.S4x*(1) - app.S4x_01;
            dy4_0 = app.S4y*(1) - app.S4y_01;
            dz4_0 = app.S4z - app.S4z_01;
            zone = 0;
            fz = 1;
            
  % when there is no shear foce
  if (dx1_0^2+dy1_0^2)>10000  % exist shear force

            dx1 = dx1_0;
            dy1 = dy1_0;
            dz1 = dz1_0;

            dx2 = (dx2_0/2000 - dx1/4000) * 2000;
            dy2 = (dy2_0/2000 - dy1/4000) * 2000;
            dz2 = dz2_0;
            dx3 = (dx3_0/2000 - dx1/4000) * 2000;
            dy3 = (dy2_0/2000 - dx1/4000) * 2000;
            dz3 = dz3_0;

  else % no shear force
            dx1 = dx1_0;
            dy1 = dy1_0;
            dz1 = dz1_0;
            dx2 = dx2_0;
            dy2 = dy2_0;
            dz2 = dz2_0;
            dx3 = dx3_0;
            dy3 = dy3_0;
            dz3 = dz3_0;
            dx4 = dx4_0;
            dy4 = dy4_0;
            dz4 = dz4_0;
  end
          
  %------------using python
  data0 = [dx1 dy1 dz1 dx2 dy2 dz2 dx3 dy3 dz3 dx4 dy4 dz4];
  % rot = zeros(1,3);
  maxlist = [508.2	1322.9999	3164.3921	1270.8001	4005	394.702	362.1001	3278.1	372.6802	358.8	3275.4	412.126];
  minlist = [-515.7	-2260.8	-252.164	-834.3	-2727.0002	-7517.9719	-2502.9001	-3259.8	-831.7539	-2503.5002	-3268.2	-851.8398];
  data = data0(1,:);
  data = (data - minlist) ./(maxlist- minlist);
  data = data';

  save('data.mat', 'data');

  system('python Demo_model.py');

  result = load('modelout.mat');

  ot = result.output;

  remax = [0.6,60,100];
  remin = [0,0,0];

  rot = ot .* (remax-remin) + remin;

  app.Fz = rot(1,1);
  app.X_Location = rot(1,2);
  app.Y_Location = rot(1,3);


end
%------------------------------------------------------------------------------------

%----------------------------------------------------------------------------------------------------
        function updatePlotView(app)

            dx1 = app.S1x*(-1) - app.S1x_01;
            dy1 = app.S1y - app.S1y_01;
            % dz1 = app.S1z*(-1) - app.S1z_01;
            % dx2 = app.S2x*(-1) - app.S2x_01;
            % dy2 = app.S2y*(-1) - app.S2y_01;
            % dz2 = app.S2z - app.S2z_01;
            % dx3 = app.S3x*(-1) - app.S3x_01;
            % dy3 = app.S3y*(-1) - app.S3y_01;
            % dz3 = app.S3z - app.S3z_01;

          c = colorbar(app.UIAxes, Limits=[0 2]);
          clim(app.UIAxes,[0 2]);
          c.Label.String = 'Force';
          c.Label.FontSize = 10;
          color_map = colormap(c,turbo);  %color_map = colormap(c,sky);
          % normal force and shear force have different color
          color_value_normal = interp1(linspace(0, 2, size(color_map, 1)), color_map, app.Fz);
          color_value_shear = interp1(linspace(0, 2, size(color_map, 1)), color_map, (app.Fx^2+app.Fy^2));
          X = app.X_Location;
          Y = app.Y_Location;

          if app.X_Location > 0 % adjust if there is avaliable signal

              if (dx1^2+dy1^2)>10000  % exist shear force
              U = dx1 / sqrt(dx1^2 + dy1^2);
              V = dy1 / sqrt(dx1^2 + dy1^2);
              quiver(app.UIAxes,X,Y,U,V,'o',LineWidth=2,Color=color_value_shear,ShowArrowHead=1,MarkerMode='manual',MarkerEdgeColor=color_value_normal);
              else % no shear force
              plot(app.UIAxes,X,Y,'o',LineWidth=6,Color=color_value_normal);
              end
              
          else
              plot(app.UIAxes,1,1,'-',LineWidth=1,Color=[1,1,1]); % if no signal, plot white image
          end  
          

        end
%-----------------------------------------------------------------------------------------------------


        function clearLiveDataFields(app)
            %clearLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to be empty values. Disable the lamp
            %   indicating GPS status (no fix), since there is no data to show.

            app.SensorStatusLamp.Color = [0.9 0.9 0.9];
            app.SensorStatusField.Value = '0';
            app.S1xField.Value = "-";
            app.S1yField.Value = "-";
            app.S1zField.Value = "-";
            app.S2xField.Value = "-";
            app.S2yField.Value = "-";
            app.S2zField.Value = "-";
            app.S3xField.Value = "-";
            app.S3zField.Value = "-";
            app.S3yField.Value = "-";
            app.X_LocationField.Value = "-";
            app.Y_LocationField.Value = "-";
            app.FxField.Value = "-";
            app.FyField.Value = "-";
            app.FzField.Value = "-";
        end

%-----------------------------------------------------------------------------------------------------
        function resetData(app)
            %resetData Reset all of the saved data from the app properties.

            app.FixType = 0;
            app.S1x = 0;
            app.S1y = 0;
            app.S1z = 0;
            app.S2x = 0;
            app.S2y = 0;
            app.S2z = 0;
            app.S3x = 0;
            app.S3y = 0;
            app.S3z = 0;
            % app.X_Location = 0;
            % app.Y_Location = 0;
            % app.Fx = 0;
            % app.Fy = 0;
            % app.Fz = 0;

            app.NMEARaw = [];
            app.Logging = false;
            app.FileInitialized = false;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up other components
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
            app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);

            ylim(app.UIAxes,[0, 7])
            xlim(app.UIAxes,[0, 7])
            setAppState(app,"DeviceSelection");
            app.Count = 0;
            %get_initial_data(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            setAppState(app,"Connected");
            drawnow

            % Validate selected serial port device
            try
                app.SerialPort = serialport(app.SerialportDropDown.Value,str2double(app.BaudrateDropDown.Value));
            catch ME
                uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                setAppState(app,"DeviceSelection");
                return
            end

            % Set terminator and callback
            configureTerminator(app.SerialPort,"CR/LF")
            configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))

            % Reset app properties
            resetData(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.SerialPort)
            StopButtonPushed(app);
            setAppState(app,"DeviceSelection");
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            [app.Filename, app.FilePath] = uiputfile(".txt");
            figure(app.UIFigure)
            if ~isequal(app.Filename,0) && ~isequal(app.FilePath,0)
                app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If there is no file initialized, make sure to check for a
            % valid file name before starting to log
            if ~app.FileInitialized
                [name, raw] = createFileName(app,app.FilenameEditField.Value);
                app.FilenameEditField.Value = name;
                app.RawFilename = raw;
            end
            
            app.Logging = true;

            setAppState(app,"Logging");
        end

        % Value changed function: FilterByDropDown
        function FilterByDropDownValueChanged(app, event)
            updateNMEALog(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab
                case "PlotViewTab"
                    updatePlotView(app);
                case "NMEALogTab"
                    updateNMEALog(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Logging = false;
            % Do not change FileInitialized, as keeping the file name
            % will mean you continue logging to the same file
            setAppState(app,"WaitForData");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = "";
            if ~isempty(app.SerialPort) && isvalid(app.SerialPort)
                selection = uiconfirm(app.UIFigure,"Close app and serial port connection?","Confirm Close");
            end
            if selection ~= "Cancel"
                delete(app)
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            [name, raw] = createFileName(app,app.FilenameEditField.Value);
            % Reset FileInitialized only when the file name is changed
            % if the file name is not changed, continue to write to the
            % existing file, appending new data to the end
            app.FileInitialized = false;
            app.FilenameEditField.Value = name;
            app.RawFilename = raw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.ConfigurationPanel = uipanel(app.ConfigGrid);
            app.ConfigurationPanel.Title = 'Configuration';
            app.ConfigurationGrid = uigridlayout(app.ConfigurationPanel);
            app.SerialportDropDownLabel = uilabel(app.ConfigurationGrid);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigurationGrid);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.Placeholder = 'Select...';
            app.SerialportDropDown.Value = {};
            app.RefreshButton = uibutton(app.ConfigurationGrid, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '';
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'refresh.svg');
            app.BaudRateLabel = uilabel(app.ConfigurationGrid);
            app.BaudRateLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.ConfigurationGrid);
            app.BaudrateDropDown.Items = {'9600', '19200', '115200'};
            app.BaudrateDropDown.Editable = 'on';
            app.BaudrateDropDown.Placeholder = 'Select...';
            app.BaudrateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.FileLoggingPanel = uipanel(app.ConfigGrid);
            app.FileLoggingPanel.Title = 'File Logging';
            app.FileLoggingGrid = uigridlayout(app.FileLoggingPanel);
            app.FilenameLabel = uilabel(app.FileLoggingGrid);
            app.FilenameLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileLoggingGrid, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'GPS_data.txt';
            app.FileSelectButton = uibutton(app.FileLoggingGrid, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Text = '';
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'open.svg');
            app.StartButton = uibutton(app.FileLoggingGrid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.FileLoggingGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.LiveDataGrid = uigridlayout(app.GridLayout);
            app.SensorstatusLabel = uilabel(app.LiveDataGrid);
            app.SensorstatusLabel.Text = 'Sensor status:';
            app.SensorStatusLamp = uilamp(app.LiveDataGrid);
            app.SensorStatusField = uieditfield(app.LiveDataGrid, 'text');
            app.SensorStatusField.Editable = 'off';
            app.SensorStatusField.Value = '-';
            app.S1xLabel = uilabel(app.LiveDataGrid);
            app.S1xLabel.Text = 'S1x';
            app.S1xField = uieditfield(app.LiveDataGrid, 'text');
            app.S1xField.Editable = 'off';
            app.S1xField.Value = '-';
            app.S1yLabel = uilabel(app.LiveDataGrid);
            app.S1yLabel.Text = 'S1y';
            app.S1yField = uieditfield(app.LiveDataGrid, 'text');
            app.S1yField.Editable = 'off';
            app.S1yField.Value = '-';
            app.S1zLabel = uilabel(app.LiveDataGrid);
            app.S1zLabel.Text = 'S1z';
            app.S1zField = uieditfield(app.LiveDataGrid, 'text');
            app.S1zField.Editable = 'off';
            app.S1zField.Value = '-';
            app.S2xLabel = uilabel(app.LiveDataGrid);
            app.S2xLabel.Text = 'S2x';
            app.S2xField = uieditfield(app.LiveDataGrid, 'text');
            app.S2xField.Editable = 'off';
            app.S2xField.Value = '-';
            app.S2yLabel = uilabel(app.LiveDataGrid);
            app.S2yLabel.Text = 'S2y';
            app.S2yField = uieditfield(app.LiveDataGrid, 'text');
            app.S2yField.Editable = 'off';
            app.S2yField.Value = '-';
            app.S2zLabel = uilabel(app.LiveDataGrid);
            app.S2zLabel.Text = 'S2z';
            app.S2zField = uieditfield(app.LiveDataGrid, 'text');
            app.S2zField.Editable = 'off';
            app.S2zField.Value = '-';
            app.S3xLabel = uilabel(app.LiveDataGrid);
            app.S3xLabel.Text = 'S3x';
            app.S3xField = uieditfield(app.LiveDataGrid, 'text');
            app.S3xField.Editable = 'off';
            app.S3xField.Value = '-';
            app.S3yLabel = uilabel(app.LiveDataGrid);
            app.S3yLabel.Text = 'S3y';
            app.S3yField = uieditfield(app.LiveDataGrid, 'text');
            app.S3yField.Editable = 'off';
            app.S3yField.Value = '-';
            app.S3zLabel = uilabel(app.LiveDataGrid);
            app.S3zLabel.Text = 'S3z';
            app.S3zField = uieditfield(app.LiveDataGrid, 'text');
            app.S3zField.Editable = 'off';
            app.S3zField.Value = '-';
            app.X_LocationLabel = uilabel(app.LiveDataGrid);
            app.X_LocationLabel.Text = 'X_Location';
            app.X_LocationField = uieditfield(app.LiveDataGrid, 'text');
            app.X_LocationField.Editable = 'off';
            app.X_LocationField.Value = '-';
            app.Y_LocationLabel = uilabel(app.LiveDataGrid);
            app.Y_LocationLabel.Text = 'Y_Location';
            app.Y_LocationField = uieditfield(app.LiveDataGrid, 'text');
            app.Y_LocationField.Editable = 'off';
            app.Y_LocationField.Value = '-';
            app.FxLabel = uilabel(app.LiveDataGrid);
            app.FxLabel.Text = 'Fx';
            app.FyLabel = uilabel(app.LiveDataGrid);
            app.FyLabel.Text = 'Fy';
            app.FzLabel = uilabel(app.LiveDataGrid);
            app.FzLabel.Text = 'Fz';
            app.FzField = uieditfield(app.LiveDataGrid, 'text');
            app.FzField.Editable = 'off';
            app.FzField.Value = '-';
            app.FxField = uieditfield(app.LiveDataGrid, 'text');
            app.FxField.Editable = 'off';
            app.FxField.Value = '-';
            app.FyField = uieditfield(app.LiveDataGrid, 'text');
            app.FyField.Editable = 'off';
            app.FyField.Value = '-';
            app.S4xLabel = uilabel(app.LiveDataGrid);
            app.S4xLabel.Text = 'S4x';
            app.S4yLabel = uilabel(app.LiveDataGrid);
            app.S4yLabel.Text = 'S4y';
            app.S4zLabel = uilabel(app.LiveDataGrid);
            app.S4zLabel.Text = 'S4z';
            app.S4xField = uieditfield(app.LiveDataGrid, 'text');
            app.S4xField.Editable = 'off';
            app.S4xField.Value = '-';
            app.S4yField = uieditfield(app.LiveDataGrid, 'text');
            app.S4yField.Editable = 'off';
            app.S4yField.Value = '-';
            app.S4zField = uieditfield(app.LiveDataGrid, 'text');
            app.S4zField.Editable = 'off';
            app.S4zField.Value = '-';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 6];
            app.UIAxes.YLim = [0 6];
            app.NMEALogTab = uitab(app.TabGroup);
            app.NMEALogTab.Title = 'NMEA Log';
            app.LogGrid = uigridlayout(app.NMEALogTab);
            app.FilterByDropDownLabel = uilabel(app.LogGrid);
            app.FilterByDropDownLabel.Text = 'Filter By';
            app.FilterByDropDown = uidropdown(app.LogGrid);
            app.FilterByDropDown.Items = {'All'};
            app.FilterByDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterByDropDownValueChanged, true);
            app.FilterByDropDown.Value = 'All';
            app.NMEAStringTextArea = uitextarea(app.LogGrid);
            app.NMEAStringTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialPortSensorApp_4FPC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end